classdef Interface_V5_1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        HomeTab                         matlab.ui.container.Tab
        Image2                          matlab.ui.control.Image
        Image                           matlab.ui.control.Image
        CristinaRodrguezArriazaLabel    matlab.ui.control.Label
        DavidRubioEspinosadelosMonterosAliagaLabel  matlab.ui.control.Label
        HARMEvaluationMethodLabel       matlab.ui.control.Label
        ProjectDataTab                  matlab.ui.container.Tab
        TaskDescription                 matlab.ui.control.EditField
        TaskDescriptionEditFieldLabel   matlab.ui.control.Label
        SelectedFile                    matlab.ui.control.EditField
        Label                           matlab.ui.control.Label
        SelectFilemvnxButton            matlab.ui.control.Button
        Date                            matlab.ui.control.DatePicker
        DateDatePickerLabel             matlab.ui.control.Label
        Completedby                     matlab.ui.control.EditField
        CompletedbyEditFieldLabel       matlab.ui.control.Label
        Department                      matlab.ui.control.EditField
        DepartmentEditFieldLabel        matlab.ui.control.Label
        TaskDurationTab                 matlab.ui.container.Tab
        HowmanydaysperweekdoesthetaskoccurDropDown  matlab.ui.control.DropDown
        HowmanydaysperweekdoesthetaskoccurDropDownLabel  matlab.ui.control.Label
        taskdurationdropdown            matlab.ui.control.DropDown
        DropDown_4Label                 matlab.ui.control.Label
        hoursdayLabel                   matlab.ui.control.Label
        daysLabel                       matlab.ui.control.Label
        Isabreakofatleast75minutestakenevery15hoursDropDown  matlab.ui.control.DropDown
        Isabreakofatleast75minutestakenevery15hoursDropDownLabel  matlab.ui.control.Label
        OtherFactorsTab                 matlab.ui.container.Tab
        Precisiontask                   matlab.ui.control.DropDown
        suchasassemblyofverysmallpiecesorsurgicalactionsLabel  matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        Handsgrip                       matlab.ui.control.DropDown
        Stretchedfingersora2or3fingerpinchLabel  matlab.ui.control.Label
        HandgripsarenotshapedorareslipperyorwetLabel  matlab.ui.control.Label
        Disruptiontoconcentration       matlab.ui.control.DropDown
        DropDownLabel                   matlab.ui.control.Label
        Workwithcoldorwetmaterialsisperformedwithoutgloves  matlab.ui.control.DropDown
        WorkwithcoldorwetmaterialsisperformedwithoutglovesDropDownLabel  matlab.ui.control.Label
        Breakscanonlybetakenatsetbreaktimes  matlab.ui.control.DropDown
        BreakscanonlybetakenatsetbreaktimesDropDownLabel  matlab.ui.control.Label
        ForcesTab                       matlab.ui.container.Tab
        numminLabel_4                   matlab.ui.control.Label
        NLabel_4                        matlab.ui.control.Label
        sminLabel_4                     matlab.ui.control.Label
        numminLabel_3                   matlab.ui.control.Label
        NLabel_3                        matlab.ui.control.Label
        sminLabel_3                     matlab.ui.control.Label
        numminLabel_2                   matlab.ui.control.Label
        NLabel_2                        matlab.ui.control.Label
        sminLabel_2                     matlab.ui.control.Label
        numminLabel                     matlab.ui.control.Label
        NLabel                          matlab.ui.control.Label
        sminLabel                       matlab.ui.control.Label
        DurationoftheforceexertionEditField_4Label  matlab.ui.control.Label
        Durationoftheforceexertion4     matlab.ui.control.NumericEditField
        Frequencyofforceexertion4       matlab.ui.control.NumericEditField
        FrequencyofforceexertionEditField_4Label  matlab.ui.control.Label
        AmountofforceDropDownLabel_4    matlab.ui.control.Label
        Amountofforce4                  matlab.ui.control.DropDown
        FORCE4Label                     matlab.ui.control.Label
        DurationoftheforceexertionEditField_3Label  matlab.ui.control.Label
        Durationoftheforceexertion3     matlab.ui.control.NumericEditField
        Frequencyofforceexertion3       matlab.ui.control.NumericEditField
        FrequencyofforceexertionEditField_3Label  matlab.ui.control.Label
        AmountofforceDropDownLabel_3    matlab.ui.control.Label
        Amountofforce3                  matlab.ui.control.DropDown
        FORCE3Label                     matlab.ui.control.Label
        DurationoftheforceexertionEditField_2Label  matlab.ui.control.Label
        Durationoftheforceexertion2     matlab.ui.control.NumericEditField
        Frequencyofforceexertion2       matlab.ui.control.NumericEditField
        FrequencyofforceexertionEditField_2Label  matlab.ui.control.Label
        AmountofforceDropDownLabel_2    matlab.ui.control.Label
        Amountofforce2                  matlab.ui.control.DropDown
        FORCE2Label                     matlab.ui.control.Label
        Frequencyofforceexertion1       matlab.ui.control.NumericEditField
        FrequencyofforceexertionEditFieldLabel  matlab.ui.control.Label
        Durationoftheforceexertion1     matlab.ui.control.NumericEditField
        DurationoftheforceexertionEditFieldLabel  matlab.ui.control.Label
        Amountofforce1                  matlab.ui.control.DropDown
        AmountofforceDropDownLabel      matlab.ui.control.Label
        FORCE1Label                     matlab.ui.control.Label
        SelectedFileForces              matlab.ui.control.EditField
        SelectedFileEditFieldLabel      matlab.ui.control.Label
        SelectForcesFiletxtButton       matlab.ui.control.Button
        VibrationsTab                   matlab.ui.container.Tab
        Vibrationsclearlyvisible        matlab.ui.control.DropDown
        DropDownLabel_4                 matlab.ui.control.Label
        Vibrationsvisiblelowerarm       matlab.ui.control.DropDown
        DropDownLabel_3                 matlab.ui.control.Label
        Vibrationsnotvisiblebutperceived  matlab.ui.control.DropDown
        DropDownLabel_2                 matlab.ui.control.Label
        Hardlyanyvibration25ms          matlab.ui.control.DropDown
        Hardlyanyvibration25msDropDownLabel  matlab.ui.control.Label
        EvaluationsTab                  matlab.ui.container.Tab
        SaveButton                      matlab.ui.control.Button
        EvaluateButton                  matlab.ui.control.Button
        TabGroup2                       matlab.ui.container.TabGroup
        JointresultsTab                 matlab.ui.container.Tab
        TabGroup3                       matlab.ui.container.TabGroup
        HeadNeck4A15Tab                 matlab.ui.container.Tab
        score4a5                        matlab.ui.control.NumericEditField
        score4a4                        matlab.ui.control.NumericEditField
        score4a3                        matlab.ui.control.NumericEditField
        score4a2                        matlab.ui.control.NumericEditField
        score4a1                        matlab.ui.control.NumericEditField
        StepScoreLabel                  matlab.ui.control.Label
        cmLabel                         matlab.ui.control.Label
        MaxHeadExtensionEditField       matlab.ui.control.NumericEditField
        MaxHeadExtensionEditFieldLabel  matlab.ui.control.Label
        Label_23                        matlab.ui.control.Label
        Headchinpushedextendedforward   matlab.ui.control.NumericEditField
        HeadchinpushedextendedforwardEditFieldLabel  matlab.ui.control.Label
        STEP4A5Label                    matlab.ui.control.Label
        Label_12                        matlab.ui.control.Label
        Label_11                        matlab.ui.control.Label
        Label_10                        matlab.ui.control.Label
        Label_8                         matlab.ui.control.Label
        Label_7                         matlab.ui.control.Label
        Label_6                         matlab.ui.control.Label
        Label_4                         matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        neck20_10                       matlab.ui.control.NumericEditField
        PercentTimethatHeadistiltedforward20ORtiltedbackward1045Label  matlab.ui.control.Label
        MaxRotationL                    matlab.ui.control.NumericEditField
        MaxRotationLEditFieldLabel      matlab.ui.control.Label
        MaxRotationR                    matlab.ui.control.NumericEditField
        MaxRotationREditFieldLabel      matlab.ui.control.Label
        ADDITIONALDATALabel             matlab.ui.control.Label
        STEP4A4Label                    matlab.ui.control.Label
        STEP4A3Label                    matlab.ui.control.Label
        STEP4A2Label                    matlab.ui.control.Label
        STEP4A1Label                    matlab.ui.control.Label
        neck10and45                     matlab.ui.control.NumericEditField
        PercentTimethatHeadistiltedbackward10ANDturned45EditFieldLabel  matlab.ui.control.Label
        neck20and45                     matlab.ui.control.NumericEditField
        PercentTimethatHeadistiltedForward20ANDturned45EditFieldLabel  matlab.ui.control.Label
        neck20_45                       matlab.ui.control.NumericEditField
        PercentTimethatHeadistiltedsideward20ORturned45EditFieldLabel  matlab.ui.control.Label
        MaxExtension                    matlab.ui.control.NumericEditField
        MaxExtensionLabel               matlab.ui.control.Label
        MaxFlexion                      matlab.ui.control.NumericEditField
        MaxFlexionLabel                 matlab.ui.control.Label
        Shoulders4A67Tab                matlab.ui.container.Tab
        shouldersidewardsleftright      matlab.ui.control.NumericEditField
        MaxExtensionLabel_6             matlab.ui.control.Label
        shouldersidewardsrightright     matlab.ui.control.NumericEditField
        MaxFlexionLabel_6               matlab.ui.control.Label
        shoulderbackwardsright          matlab.ui.control.NumericEditField
        MaxExtensionLabel_5             matlab.ui.control.Label
        shoulderforwardright            matlab.ui.control.NumericEditField
        MaxFlexionLabel_5               matlab.ui.control.Label
        Label_56                        matlab.ui.control.Label
        Label_55                        matlab.ui.control.Label
        Label_54                        matlab.ui.control.Label
        Label_53                        matlab.ui.control.Label
        shouldersidewardsleftleft       matlab.ui.control.NumericEditField
        MaxExtensionLabel_4             matlab.ui.control.Label
        shouldersidewardsrightleft      matlab.ui.control.NumericEditField
        MaxFlexionLabel_4               matlab.ui.control.Label
        Label_52                        matlab.ui.control.Label
        Label_51                        matlab.ui.control.Label
        shoulderbackwardsleft           matlab.ui.control.NumericEditField
        MaxExtensionLabel_2             matlab.ui.control.Label
        shoulderforwardsleft            matlab.ui.control.NumericEditField
        MaxFlexionLabel_2               matlab.ui.control.Label
        Label_48                        matlab.ui.control.Label
        Label_47                        matlab.ui.control.Label
        ADDITIONALDATALabel_4           matlab.ui.control.Label
        score4a7                        matlab.ui.control.NumericEditField
        score4a6L                       matlab.ui.control.NumericEditField
        score4a6R                       matlab.ui.control.NumericEditField
        StepScoreLabel_2                matlab.ui.control.Label
        Label_22                        matlab.ui.control.Label
        Label_21                        matlab.ui.control.Label
        Label_20                        matlab.ui.control.Label
        Shouldersraisedhigh3cm          matlab.ui.control.NumericEditField
        Shouldersraisedhigh3cmEditFieldLabel  matlab.ui.control.Label
        STEP4A7Label                    matlab.ui.control.Label
        STEP4A6Label                    matlab.ui.control.Label
        Upperarmforward30ORsideward30ORbackwards30Left  matlab.ui.control.NumericEditField
        Upperarmforward30ORsideward30ORbackwards30LeftEditFieldLabel  matlab.ui.control.Label
        Upperarmforward30ORsideward30ORbackwards30Right  matlab.ui.control.NumericEditField
        Upperarmforward30ORsideward30ORbackwards30RightEditFieldLabel  matlab.ui.control.Label
        Elbows4B12Tab                   matlab.ui.container.Tab
        score4b2L                       matlab.ui.control.NumericEditField
        score4b2R                       matlab.ui.control.NumericEditField
        score4b1L                       matlab.ui.control.NumericEditField
        score4b1R                       matlab.ui.control.NumericEditField
        StepScoreLabel_3                matlab.ui.control.Label
        Label_35                        matlab.ui.control.Label
        Label_34                        matlab.ui.control.Label
        Label_33                        matlab.ui.control.Label
        Label_31                        matlab.ui.control.Label
        Label_30                        matlab.ui.control.Label
        Label_29                        matlab.ui.control.Label
        Label_27                        matlab.ui.control.Label
        Label_26                        matlab.ui.control.Label
        Label_25                        matlab.ui.control.Label
        Label_24                        matlab.ui.control.Label
        MaxlowerarmsupinationRightEditField_2  matlab.ui.control.NumericEditField
        MaxlowerarmsupinationRightEditField_2Label  matlab.ui.control.Label
        MaxlowerarmpronationRightEditField_2  matlab.ui.control.NumericEditField
        MaxlowerarmpronationRightEditField_2Label  matlab.ui.control.Label
        MaxlowerarmflexionRightEditField  matlab.ui.control.NumericEditField
        MaxlowerarmflexionRightEditFieldLabel  matlab.ui.control.Label
        MaxlowerarmsupinationLeftEditField  matlab.ui.control.NumericEditField
        MaxlowerarmsupinationLeftEditFieldLabel  matlab.ui.control.Label
        MaxlowerarmpronationLeftEditField  matlab.ui.control.NumericEditField
        MaxlowerarmpronationLeftEditFieldLabel  matlab.ui.control.Label
        MaxlowerarmflexionLeftEditField  matlab.ui.control.NumericEditField
        MaxlowerarmflexionLeftEditFieldLabel  matlab.ui.control.Label
        ADDITIONALDATALabel_2           matlab.ui.control.Label
        Lowerarmrotated40fromneutralLeft  matlab.ui.control.NumericEditField
        Lowerarmrotated40fromneutralLeftEditFieldLabel  matlab.ui.control.Label
        ElbowbentTORextendedULeft       matlab.ui.control.NumericEditField
        ElbowbentTORextendedULeftEditFieldLabel  matlab.ui.control.Label
        Lowerarmrotated40fromneutralRight  matlab.ui.control.NumericEditField
        Lowerarmrotated40fromneutralRightEditFieldLabel  matlab.ui.control.Label
        STEP4B2Label                    matlab.ui.control.Label
        ElbowbentTORextendedURight      matlab.ui.control.NumericEditField
        ElbowbentTORextendedURightEditFieldLabel  matlab.ui.control.Label
        STEP4B1Label                    matlab.ui.control.Label
        Wrists4B34Tab                   matlab.ui.container.Tab
        Label_58                        matlab.ui.control.Label
        Label_57                        matlab.ui.control.Label
        MaxwristextensionR              matlab.ui.control.NumericEditField
        MaxwristextensionLeftLabel_2    matlab.ui.control.Label
        MaxwristExtensionLeft           matlab.ui.control.NumericEditField
        MaxwristextensionLeftLabel      matlab.ui.control.Label
        MaxwristbentsidewaystorightRight  matlab.ui.control.NumericEditField
        MaxwristbentsidewaystorightRightEditFieldLabel  matlab.ui.control.Label
        MaxwristbentsidewaystoleftRight  matlab.ui.control.NumericEditField
        MaxwristbentsidewaystoleftRightEditFieldLabel  matlab.ui.control.Label
        MaxwristflexionRight            matlab.ui.control.NumericEditField
        MaxwristflexionRightEditFieldLabel  matlab.ui.control.Label
        MaxwristbentsidewaystorightLeft  matlab.ui.control.NumericEditField
        MaxwristbentsidewaystorightLeftEditFieldLabel  matlab.ui.control.Label
        MaxwristbentsidewaystoleftLeft  matlab.ui.control.NumericEditField
        MaxwristbentsidewaystoleftLeftEditFieldLabel  matlab.ui.control.Label
        MaxwristflexionLeft             matlab.ui.control.NumericEditField
        MaxwristflexionLeftEditFieldLabel  matlab.ui.control.Label
        Thehandisbentatthewrist15Left   matlab.ui.control.NumericEditField
        Thehandisbentatthewrist15LeftEditFieldLabel  matlab.ui.control.Label
        Handbentsidewaysatthewrist10Left  matlab.ui.control.NumericEditField
        Handbentsidewaysatthewrist10LeftEditFieldLabel  matlab.ui.control.Label
        Thehandisbentatthewrist15Right  matlab.ui.control.NumericEditField
        Thehandisbentatthewrist15RightEditFieldLabel  matlab.ui.control.Label
        Handbentsidewaysatthewrist10Right  matlab.ui.control.NumericEditField
        Handbentsidewaysatthewrist10RightEditFieldLabel  matlab.ui.control.Label
        score4b4L                       matlab.ui.control.NumericEditField
        score4b4R                       matlab.ui.control.NumericEditField
        score4b3L                       matlab.ui.control.NumericEditField
        score4b3R                       matlab.ui.control.NumericEditField
        StepScoreLabel_4                matlab.ui.control.Label
        Label_45                        matlab.ui.control.Label
        Label_44                        matlab.ui.control.Label
        Label_43                        matlab.ui.control.Label
        Label_42                        matlab.ui.control.Label
        Label_41                        matlab.ui.control.Label
        Label_40                        matlab.ui.control.Label
        Label_39                        matlab.ui.control.Label
        Label_38                        matlab.ui.control.Label
        Label_37                        matlab.ui.control.Label
        Label_36                        matlab.ui.control.Label
        ADDITIONALDATALabel_3           matlab.ui.control.Label
        STEP4B4Label                    matlab.ui.control.Label
        STEP4B3Label                    matlab.ui.control.Label
        HARMEvaluationTab               matlab.ui.container.Tab
        HARMRiskScoreR                  matlab.ui.control.NumericEditField
        HARMRiskScoreLeftArmLabel_2     matlab.ui.control.Label
        Label_19                        matlab.ui.control.Label
        Label_18                        matlab.ui.control.Label
        Label_17                        matlab.ui.control.Label
        Label_16                        matlab.ui.control.Label
        Label_15                        matlab.ui.control.Label
        Label_14                        matlab.ui.control.Label
        Label_13                        matlab.ui.control.Label
        HARMRiskScoreL                  matlab.ui.control.NumericEditField
        HARMRiskScoreLeftArmLabel       matlab.ui.control.Label
        MostactivearmTab                matlab.ui.container.Tab
        Label_46                        matlab.ui.control.Label
        mostactivearmpercent            matlab.ui.control.NumericEditField
        byEditFieldLabel                matlab.ui.control.Label
        mostactivearm                   matlab.ui.control.EditField
        ThemostactivearmistheLabel      matlab.ui.control.Label
    end


    properties (Access = public)
        ptree % Secction for .mvnx
        pfilename % excel file
        psheetname %excel sheet
    end
    
    properties (Access = private)
        Total_Score_LEFT % Score value for HARM (left arm)
        Total_Score_RIGHT % Score value for HARM (right arm)
    end


    methods (Access = public)

        
        function evaluation = func4(app) %function for the design of the HARM results presentation 

            app.HARMRiskScoreL.Value = app.Total_Score_LEFT;
        
            if app.Total_Score_LEFT >50
                app.HARMRiskScoreL.BackgroundColor = 'r';

            elseif app.Total_Score_LEFT < 30
                app.HARMRiskScoreL.BackgroundColor = 'g';

            else
                app.HARMRiskScoreL.BackgroundColor = [1 0.5 0];

            end

            
            app.HARMRiskScoreR.Value = app.Total_Score_RIGHT;

            if app.Total_Score_RIGHT >50
                app.HARMRiskScoreR.BackgroundColor = 'r';

            elseif app.Total_Score_RIGHT < 30
                app.HARMRiskScoreR.BackgroundColor = 'g';

            else
                app.HARMRiskScoreR.BackgroundColor = [1 0.5 0];

            end


        end

        function step_scores = func9(app) %function that calculates the score for each step
            %This function calculates: 

            %Score 1
                %Score 1-1
             score1_1 = str2double(app.taskdurationdropdown.Value) - 1;
                %Score 1-2
             if app.HowmanydaysperweekdoesthetaskoccurDropDown.Value > "2"
                 score1_2 = 0;
             else
                 score1_2 = -1;
             end

             if app.Isabreakofatleast75minutestakenevery15hoursDropDown.Value == "Yes"
                 score1_3 = -1;
             else
                 score1_3 = 0;
             end

             Score1 = score1_1 + score1_2 + score1_3;


            %Score 4A1
            %Neck score if value is higher th
            if app.neck20_10.Value > 50
                app.score4a1.Value = 3;
            elseif app.neck20_10.Value < 10
                app.score4a1.Value = 0;
            else
                app.score4a1.Value = 1.5;
            end


            %Score 4A2
            if app.neck20_45.Value > 50
                app.score4a2.Value = 3;
            elseif app.neck20_45.Value < 10
                app.score4a2.Value = 0;
            else
                app.score4a2.Value = 1.5;
            end


            %Score 4A3
            if app.neck20and45.Value > 50
                app.score4a3.Value = 4;
            elseif app.neck20and45.Value < 10
                app.score4a3.Value = 0;
            else
                app.score4a3.Value = 2;
            end


            %Score 4A4
            if app.neck10and45.Value > 50
                app.score4a4.Value = 4;
            elseif app.neck10and45.Value < 10
                app.score4a4.Value = 0;
            else
                app.score4a4.Value = 3;
            end


            %Score 4A5
            if app.Headchinpushedextendedforward.Value > 50
                app.score4a5.Value = 3;
            elseif app.Headchinpushedextendedforward.Value < 10
                app.score4a5.Value = 0;
            else
                app.score4a5.Value = 1.5;
            end


            %Score 4A6R
            if app.Upperarmforward30ORsideward30ORbackwards30Right.Value > 50
                app.score4a6R.Value = 3.5;
            elseif app.Upperarmforward30ORsideward30ORbackwards30Right.Value < 10
                app.score4a6R.Value = 0;
            else
                app.score4a6R.Value = 2.5;
            end


            %Score 4A6L
            if app.Upperarmforward30ORsideward30ORbackwards30Left.Value > 50
                app.score4a6L.Value = 3.5;
            elseif app.Upperarmforward30ORsideward30ORbackwards30Left.Value < 10
                app.score4a6L.Value = 0;
            else
                app.score4a6L.Value = 2.5;
            end


            %Score 4A7
            if app.Shouldersraisedhigh3cm.Value > 50
                app.score4a7.Value = 4;
            elseif app.Shouldersraisedhigh3cm.Value < 10
                app.score4a7.Value = 0;
            else
                app.score4a7.Value = 3;
            end

            %Score 4B1R
            if app.ElbowbentTORextendedURight.Value > 50
                app.score4b1R.Value = 2;
            elseif app.ElbowbentTORextendedURight.Value < 10
                app.score4b1R.Value = 0;
            else
                app.score4b1R.Value = 1;
            end

            %Score 4B1L
            if app.ElbowbentTORextendedULeft.Value > 50
                app.score4b1L.Value = 2;
            elseif app.ElbowbentTORextendedULeft.Value < 10
                app.score4b1L.Value = 0;
            else
                app.score4b1L.Value = 1;
            end


            %Score 4B2R
            if app.Lowerarmrotated40fromneutralRight.Value > 50
                app.score4b2R.Value = 2;
            elseif app.Lowerarmrotated40fromneutralRight.Value < 10
                app.score4b2R.Value = 0;
            else
                app.score4b2R.Value = 1;
            end

            %Score 4B2L
            if app.Lowerarmrotated40fromneutralLeft.Value > 50
                app.score4b2L.Value = 2;
            elseif app.Lowerarmrotated40fromneutralLeft.Value < 10
                app.score4b2L.Value = 0;
            else
                app.score4b2L.Value = 1;
            end


            %Score 4B2L
            if app.Lowerarmrotated40fromneutralLeft.Value > 50
                app.score4b2L.Value = 2;
            elseif app.Lowerarmrotated40fromneutralLeft.Value < 10
                app.score4b2L.Value = 0;
            else
                app.score4b2L.Value = 1;
            end


            %Score 4B3R
            if app.Handbentsidewaysatthewrist10Right.Value > 50
                app.score4b3R.Value = 3;
            elseif app.Handbentsidewaysatthewrist10Right.Value < 10
                app.score4b3R.Value = 0;
            else
                app.score4b3R.Value = 1.5;
            end


            %Score 4B3L
            if app.Handbentsidewaysatthewrist10Left.Value > 50
                app.score4b3L.Value = 3;
            elseif app.Handbentsidewaysatthewrist10Left.Value < 10
                app.score4b3L.Value = 0;
            else
                app.score4b3L.Value = 1.5;
            end

            %Score 4B4R
            if app.Thehandisbentatthewrist15Right.Value > 50
                app.score4b4R.Value = 3;
            elseif app.Thehandisbentatthewrist15Right.Value < 10
                app.score4b4R.Value = 0;
            else
                app.score4b4R.Value = 1.5;
            end

            %Score 4B4L
            if app.Thehandisbentatthewrist15Left.Value > 50
                app.score4b4L.Value = 3;
            elseif app.Thehandisbentatthewrist15Left.Value < 10
                app.score4b4L.Value = 0;
            else
                app.score4b4L.Value = 1.5;
            end


            %Score 4 RightArm

            Vector4RIGHT = [app.score4a1.Value, app.score4a2.Value, app.score4a3.Value, app.score4a4.Value, app.score4a5.Value, app.score4a6R.Value, app.score4a7.Value, app.score4b1R.Value,app.score4b2R.Value, app.score4b3R.Value, app.score4b4R.Value];
            Score4RIGHT = max(Vector4RIGHT);

            %Score 4 LeftArm

            Vector4LEFT = [app.score4a1.Value, app.score4a2.Value, app.score4a3.Value, app.score4a4.Value, app.score4a5.Value, app.score4a6L.Value, app.score4a7.Value, app.score4b1L.Value,app.score4b2L.Value, app.score4b3L.Value, app.score4b4L.Value];
            Score4LEFT = max(Vector4LEFT);


            %Score 5

            %Score5_1
            score5_1 = 0;

            %Score5_2
            if app.Vibrationsnotvisiblebutperceived.Value == "No vibrating tool used"
                score5_2 = 0;
            else
                score5_2 = 2;
            end

            %Score5_3
            if app.Vibrationsvisiblelowerarm.Value == "No vibrating tool used"
                score5_3 = 0;
            elseif app.Vibrationsvisiblelowerarm.Value == "0 - 4"
                score5_3 = 2;
            else
                score5_3 = 4;
            end

            %Score5_3
            if app.Vibrationsclearlyvisible.Value == "No vibrating tool used"
                score5_4 = 0;

            else
                score5_4 = 4;
            end

            vectorstep5 = [score5_1,score5_2,score5_3,score5_4];
            Score5 = max(vectorstep5);


            %Score STEP 6

            %Score6_1
            if app.Breakscanonlybetakenatsetbreaktimes.Value == "Yes"
                score6_1 = 1;
            else
                score6_1 = 0;
            end
            
            if app.Workwithcoldorwetmaterialsisperformedwithoutgloves.Value == "Yes"
                score6_2 = 1;
            else
                score6_2 = 0;
            end

            if app.Disruptiontoconcentration.Value == "Yes"
                score6_3 = 1;
            else
                score6_3 = 0;
            end

            if app.Handsgrip.Value == "Yes"
                score6_4 = 1;
            else
                score6_4 = 0;
            end

            if app.Precisiontask.Value == "Yes"
                score6_5 = 1;
            else
                score6_5 = 0;
            end


            Score6 = score6_1 + score6_2 + score6_3 + score6_4 + score6_5;


            %TOTAL HARM SCORES

            app.Total_Score_RIGHT = Score1*(Score5 + Score4RIGHT + Score6);
            app.Total_Score_LEFT = Score1*(Score5 + Score4LEFT + Score6);
        end

        function forces = func10(app) 

            filename = app.pfilename;
            sheetname = app.psheetname;

            %Force 1

            cell = 'G28';
            writeExcel(filename, sheetname,cell,app.Amountofforce1.Value);
            cell = 'G29';
            writeExcel(filename, sheetname,cell,app.Durationoftheforceexertion1.Value);
            cell = 'G30';
            writeExcel(filename, sheetname,cell,app.Frequencyofforceexertion1.Value);

            %Force 2

            cell = 'G33';
            writeExcel(filename, sheetname,cell,app.Amountofforce2.Value);
            cell = 'G34';
            writeExcel(filename, sheetname,cell,app.Durationoftheforceexertion2.Value);
            cell = 'G35';
            writeExcel(filename, sheetname,cell,app.Frequencyofforceexertion2.Value);

            %Force 3

            cell = 'G38';
            writeExcel(filename, sheetname,cell,app.Amountofforce3.Value);
            cell = 'G39';
            writeExcel(filename, sheetname,cell,app.Durationoftheforceexertion3.Value);
            cell = 'G40';
            writeExcel(filename, sheetname,cell,app.Frequencyofforceexertion3.Value);

            %Force 4

            cell = 'G43';
            writeExcel(filename, sheetname,cell,app.Amountofforce4.Value);
            cell = 'G44';
            writeExcel(filename, sheetname,cell,app.Durationoftheforceexertion4.Value);
            cell = 'G45';
            writeExcel(filename, sheetname,cell,app.Frequencyofforceexertion4.Value);


        end

        function most_active_arm = func11(app) %function that calculates the most active arm in the task
            right_arm_sum = app.Upperarmforward30ORsideward30ORbackwards30Right.Value+app.ElbowbentTORextendedURight.Value+app.Lowerarmrotated40fromneutralRight.Value+app.Handbentsidewaysatthewrist10Right.Value+app.Thehandisbentatthewrist15Right.Value;
            left_arm_sum =  app.Upperarmforward30ORsideward30ORbackwards30Left.Value+app.ElbowbentTORextendedULeft.Value+app.Lowerarmrotated40fromneutralLeft.Value+app.Handbentsidewaysatthewrist10Left.Value+app.Thehandisbentatthewrist15Left.Value;

            if right_arm_sum >  left_arm_sum
                app.mostactivearm.Value = "RIGHT ARM";
                app.mostactivearmpercent.Value = 100 - (left_arm_sum/right_arm_sum)*100;
            else
                app.mostactivearm.Value = "LEFT ARM";
                app.mostactivearmpercent.Value = 100 - (right_arm_sum/left_arm_sum)*100;
            end

        end
        
        function write_excel = func12(app) %function that saves an Excel file with the results of the HARM evaluation


            [new_filename, new_filepath] = uiputfile('*.xlsx', 'Save file');

            % Check if the user has made a selection or closed the dialog box
            if isequal(new_filename, 0) || isequal(new_filepath, 0)
                disp('File selection canceled by the user.');
            else

                % Original file name (assuming it's in the current working directory)
                originalFileName = 'HARM_DH_4_V2.xlsx';

                % Destination folder where the file will be copied
                destinationFolder = new_filepath;

                % New name for the copied file
                newFileName = new_filename;
            end


            % Get the full path of the file if it's in the path or the current working directory
            originalFilePath = which(originalFileName);

            % Check if the file was found
            if isempty(originalFilePath)
                disp('The file was not found in the path or the current working directory.');
            else
                disp(['Full path of the file: ' originalFilePath]);
            end


            % Create a copy of the file with the same attributes
            copyfile(originalFilePath, fullfile(destinationFolder, newFileName));

            filename = fullfile(destinationFolder, newFileName);
           
            sheetname = 'Sheet1';

            %% WRITING OF GENERAL INFO

            h = waitbar(0,'Saving HARM Evaluation data. Please wait.'); %initialise load bar

            writeExcel(filename, sheetname,'F10',app.TaskDescription.Value);

            writeExcel(filename, sheetname,'F13', app.Department.Value);

            writeExcel(filename, sheetname,'I10', app.Date.Value);

            writeExcel(filename, sheetname,'I13', app.Completedby.Value);

            %% WRITING OF STEP 1
            
            waitbar(0.2,h,['Saving HARM Evaluation data. Please wait. ' num2str(20) '%']);


            writeExcel(filename, sheetname,'G18', app.taskdurationdropdown.Value);

            writeExcel(filename, sheetname,'G19', app.HowmanydaysperweekdoesthetaskoccurDropDown.Value);

            writeExcel(filename, sheetname,'G20', app.Isabreakofatleast75minutestakenevery15hoursDropDown.Value);

           
            %FORCES HERE
            
            %% WRITING OF STEP 4 (POSTURES RESULTS)

            %Step 4A

            %Head and neck (no upper arm)

            waitbar(0.3,h,['Saving HARM Evaluation data. Please wait. ' num2str(30) '%']);

            
            writeExcel(filename, sheetname,'G52', app.neck20_10.Value/100);

            writeExcel(filename, sheetname,'G53', app.neck20_45.Value/100);
            
            writeExcel(filename, sheetname,'G54', app.neck20and45.Value/100);

            writeExcel(filename, sheetname,'G55', app.neck10and45.Value/100);

            writeExcel(filename, sheetname,'G56', app.Headchinpushedextendedforward.Value/100);

            writeExcel(filename, sheetname,'G58', app.Shouldersraisedhigh3cm.Value/100);


            %Upper arms

            waitbar(0.5,h,['Saving HARM Evaluation data. Please wait. ' num2str(50) '%']);

            %Right
            writeExcel(filename, sheetname,'G57', app.Upperarmforward30ORsideward30ORbackwards30Right.Value/100);
            
            %Left
            writeExcel(filename, sheetname,'G76', app.Upperarmforward30ORsideward30ORbackwards30Left.Value/100);


            waitbar(0.6,h,['Saving HARM Evaluation data. Please wait. ' num2str(60) '%']);

            %Step 4B RIGHT
            writeExcel(filename, sheetname,'G62', app.ElbowbentTORextendedURight.Value/100);
            writeExcel(filename, sheetname,'G63', app.Lowerarmrotated40fromneutralRight.Value/100);
            writeExcel(filename, sheetname,'G64', app.Handbentsidewaysatthewrist10Right.Value/100);
            writeExcel(filename, sheetname,'G65', app.Thehandisbentatthewrist15Right.Value/100);

            %Step 4B LEFT
            writeExcel(filename, sheetname,'G81', app.ElbowbentTORextendedULeft.Value/100);
            writeExcel(filename, sheetname,'G82', app.Lowerarmrotated40fromneutralLeft.Value/100);
            writeExcel(filename, sheetname,'G83', app.Handbentsidewaysatthewrist10Left.Value/100);
            writeExcel(filename, sheetname,'G84', app.Thehandisbentatthewrist15Left.Value/100);


            %% WRITING OF STEP 5 (VIBRATIONS)

            waitbar(0.8,h,['Saving HARM Evaluation data. Please wait. ' num2str(80) '%']);

            writeExcel(filename, sheetname,'G90', app.Hardlyanyvibration25ms.Value/100);
            writeExcel(filename, sheetname,'G91', app.Vibrationsnotvisiblebutperceived.Value/100);
            writeExcel(filename, sheetname,'G92', app.Vibrationsvisiblelowerarm.Value/100);
            writeExcel(filename, sheetname,'G93', app.Vibrationsclearlyvisible.Value/100);

            %% WRITING OF STEP 6 (OTHER FACTORS)

            waitbar(0.9,h,['Saving HARM Evaluation data. Please wait. ' num2str(90) '%']);

            writeExcel(filename, sheetname,'G97', app.Breakscanonlybetakenatsetbreaktimes.Value/100);
            writeExcel(filename, sheetname,'G98', app.Workwithcoldorwetmaterialsisperformedwithoutgloves.Value/100);
            writeExcel(filename, sheetname,'G99', app.Disruptiontoconcentration.Value/100);
            writeExcel(filename, sheetname,'G100', app.Handsgrip.Value/100);
            writeExcel(filename, sheetname,'G101', app.Precisiontask.Value/100);

            waitbar(1,h,['File Saved ' num2str(100) '%']);
            msgbox('File Saved');

            close(h);


        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function DayDropDownValueChanged(app, event)
        
        end

        % Button pushed function: SelectFilemvnxButton
        function SelectFilemvnxButtonPushed(app, event)

            %THIS FUNCTION ALLOWS SELECTING A .MVNX FILE TO UPLOAD IT TO
            %THE INTERFACE

            % Create a dialog box for file selection
            [file,path] = uigetfile('*.mvnx','Select the MVNX file');

            % Check if a file was selected
            if isequal(file,0)
                % Display message box
                msgbox('No file was selected','Error','error');
                return;
            else
                % Read the MVNX file
                app.ptree = load_mvnx(strcat(path,file));
            end

            app.SelectedFile.Value = file;

            msgbox('File upload completed!');


        end

        % Button pushed function: EvaluateButton
        function RunProgramButtonPushed(app, event)

            %THIS FUNCTION ALLOWS TO EXECUTE ALL OTHER INTERNAL AND
            %EXTERNAL FUNCTIONS TO CALCULATE HARM BY PRESSING THE BUTTON
            %"EVALUATE"
            
            if ( app.SelectedFile.Value == "")
                msgbox('Please, select a .mvnx file');
                return;
            else


                h = waitbar(0,'Starting Evaluation...'); %initialise load bar


                % mvnx postures 

                waitbar(0.2,h,['Processing MVNX data ' num2str(20) '%']);

                %THIS EXTERNAL FUNCTION CALCULATES ALL POSTURE VALUES FROM
                %THE .MVNX FILE
                [app.MaxFlexion.Value,app.MaxExtension.Value, app.neck20_10.Value,app.neck20_45.Value, app.neck20and45.Value,app.MaxRotationR.Value,app.MaxRotationL.Value,app.neck10and45.Value,app.Headchinpushedextendedforward.Value,app.MaxHeadExtensionEditField.Value,app.Upperarmforward30ORsideward30ORbackwards30Right.Value,app.shoulderbackwardsright.Value,app.shoulderforwardright.Value,app.shouldersidewardsrightright.Value,app.shouldersidewardsleftright.Value,app.Upperarmforward30ORsideward30ORbackwards30Left.Value,app.shoulderbackwardsleft.Value,app.shoulderforwardsleft.Value,app.shouldersidewardsrightleft.Value,app.shouldersidewardsleftleft.Value,app.Shouldersraisedhigh3cm.Value,app.MaxlowerarmflexionRightEditField.Value,app.ElbowbentTORextendedURight.Value,app.MaxlowerarmflexionLeftEditField.Value,app.ElbowbentTORextendedULeft.Value,app.MaxlowerarmpronationRightEditField_2.Value,app.MaxlowerarmsupinationRightEditField_2.Value,app.Lowerarmrotated40fromneutralRight.Value,app.MaxlowerarmpronationLeftEditField.Value,app.MaxlowerarmsupinationLeftEditField.Value,app.Lowerarmrotated40fromneutralLeft.Value,app.MaxwristbentsidewaystorightRight.Value,app.MaxwristbentsidewaystoleftRight.Value,app.Handbentsidewaysatthewrist10Right.Value,app.MaxwristbentsidewaystorightLeft.Value,app.MaxwristbentsidewaystoleftLeft.Value,app.Handbentsidewaysatthewrist10Left.Value,app.MaxwristflexionRight.Value,app.MaxwristextensionR.Value,app.Thehandisbentatthewrist15Right.Value,app.MaxwristflexionLeft.Value,app.MaxwristExtensionLeft.Value,app.Thehandisbentatthewrist15Left.Value]= postures(app.ptree);


                %forces

                %waitbar(0.5,h,['Processing forces data ' num2str(50) '%']);
                %func10(app)

             
                %step scores
                waitbar(0.6,h,['Calculating scores ' num2str(80) '%']);
                func9(app);

                %evaluation
                waitbar(0.7,h,['Evaluating ' num2str(70) '%']);
                func4(app);


                %most_active_arm
                waitbar(0.9,h,['Calculating most active arm ' num2str(90) '%']);
                func11(app);

                waitbar(1,h,['Evaluation Completed ' num2str(100) '%']);

                close(h);

                msgbox('Evaluation Completed!');


            end


        end

        % Value changed function: TaskDescription
        function TaskDescriptionValueChanged(app, event)
            value = app.TaskDescription.Value;

        end

        % Callback function
        function TotaltimedurationofthetaskoverthecourseofanaverageEditFieldValueChanged(app, event)
            
        end

        % Callback function
        function HowmanydaysperweekdoesthetaskoccurEditFieldValueChanged(app, event)
            
        end

        % Value changed function: 
        % Isabreakofatleast75minutestakenevery15hoursDropDown
        function Isabreakofatleast75minutestakenevery15hoursDropDownValueChanged(app, event)
           
        end

        % Value changed function: Breakscanonlybetakenatsetbreaktimes
        function BreakscanonlybetakenatsetbreaktimesValueChanged(app, event)
            
        end

        % Value changed function: 
        % Workwithcoldorwetmaterialsisperformedwithoutgloves
        function WorkwithcoldorwetmaterialsisperformedwithoutglovesValueChanged(app, event)
            
        end

        % Value changed function: Disruptiontoconcentration
        function DisruptiontoconcentrationValueChanged(app, event)
            
        end

        % Value changed function: Handsgrip
        function HandsgripValueChanged(app, event)
            
        end

        % Value changed function: Precisiontask
        function PrecisiontaskValueChanged(app, event)
            
        end

        % Value changed function: taskdurationdropdown
        function taskdurationdropdownValueChanged(app, event)
            
        end

        % Value changed function: 
        % HowmanydaysperweekdoesthetaskoccurDropDown
        function HowmanydaysperweekdoesthetaskoccurDropDownValueChanged(app, event)
           
        end

        % Value changed function: neck20_45
        function neck20_45ValueChanged(app, event)
            value = app.neck20_45.Value;

        end

        % Value changed function: neck20_10
        function neck20_10ValueChanged(app, event)
            value = app.neck20_10.Value;

        end

        % Value changed function: neck20and45
        function neck20and45ValueChanged(app, event)
            value = app.neck20and45.Value;

        end

        % Value changed function: neck10and45
        function neck10and45ValueChanged(app, event)
            value = app.neck10and45.Value;

        end

        % Value changed function: MaxFlexion
        function MaxFlexionValueChanged(app, event)
            value = app.MaxFlexion.Value;

        end

        % Value changed function: MaxExtension
        function MaxExtensionValueChanged(app, event)
            value = app.MaxExtension.Value;

        end

        % Value changed function: MaxRotationR
        function MaxRotationRValueChanged(app, event)
            value = app.MaxRotationR.Value;

        end

        % Value changed function: MaxRotationL
        function MaxRotationLValueChanged(app, event)
            value = app.MaxRotationL.Value;

        end

        % Value changed function: HARMRiskScoreL
        function HARMRiskScoreLValueChanged(app, event)
            value = app.HARMRiskScoreL.Value;

        end

        % Value changed function: Headchinpushedextendedforward
        function HeadchinpushedextendedforwardValueChanged(app, event)
            value = app.Headchinpushedextendedforward.Value;

        end

        % Value changed function: MaxHeadExtensionEditField
        function MaxHeadExtensionEditFieldValueChanged(app, event)
            value = app.MaxHeadExtensionEditField.Value;

        end

        % Value changed function: ElbowbentTORextendedURight
        function ElbowbentTORextendedURightValueChanged(app, event)
            value = app.ElbowbentTORextendedURight.Value;

        end

        % Value changed function: ElbowbentTORextendedULeft
        function ElbowbentTORextendedULeftValueChanged(app, event)
            value = app.ElbowbentTORextendedULeft.Value;

        end

        % Value changed function: MaxlowerarmflexionRightEditField
        function MaxlowerarmflexionRightEditFieldValueChanged(app, event)
            value = app.MaxlowerarmflexionRightEditField.Value;

        end

        % Value changed function: MaxlowerarmflexionLeftEditField
        function MaxlowerarmflexionLeftEditFieldValueChanged(app, event)
            value = app.MaxlowerarmflexionLeftEditField.Value;

        end

        % Value changed function: Lowerarmrotated40fromneutralRight
        function Lowerarmrotated40fromneutralRightValueChanged(app, event)
            value = app.Lowerarmrotated40fromneutralRight.Value;

        end

        % Value changed function: MaxlowerarmpronationRightEditField_2
        function MaxlowerarmpronationRightEditField_2ValueChanged(app, event)
            value = app.MaxlowerarmpronationRightEditField_2.Value;

        end

        % Value changed function: MaxlowerarmsupinationRightEditField_2
        function MaxlowerarmsupinationRightEditField_2ValueChanged(app, event)
            value = app.MaxlowerarmsupinationRightEditField_2.Value;

        end

        % Value changed function: Lowerarmrotated40fromneutralLeft
        function Lowerarmrotated40fromneutralLeftValueChanged(app, event)
            value = app.Lowerarmrotated40fromneutralLeft.Value;

        end

        % Value changed function: MaxlowerarmpronationLeftEditField
        function MaxlowerarmpronationLeftEditFieldValueChanged(app, event)
            value = app.MaxlowerarmpronationLeftEditField.Value;

        end

        % Value changed function: MaxlowerarmsupinationLeftEditField
        function MaxlowerarmsupinationLeftEditFieldValueChanged(app, event)
            value = app.MaxlowerarmsupinationLeftEditField.Value;

        end

        % Value changed function: 
        % Upperarmforward30ORsideward30ORbackwards30Right
        function Upperarmforward30ORsideward30ORbackwards30RightValueChanged(app, event)
            value = app.Upperarmforward30ORsideward30ORbackwards30Right.Value;

        end

        % Value changed function: 
        % Upperarmforward30ORsideward30ORbackwards30Left
        function Upperarmforward30ORsideward30ORbackwards30LeftValueChanged(app, event)
            value = app.Upperarmforward30ORsideward30ORbackwards30Left.Value;

        end

        % Value changed function: Shouldersraisedhigh3cm
        function Shouldersraisedhigh3cmValueChanged(app, event)
            value = app.Shouldersraisedhigh3cm.Value;

        end

        % Button down function: HARMEvaluationTab
        function HARMEvaluationTabButtonDown(app, event)

        end

        % Value changed function: Hardlyanyvibration25ms
        function Hardlyanyvibration25msValueChanged(app, event)
            value = app.Hardlyanyvibration25ms.Value;

        end

        % Value changed function: Vibrationsnotvisiblebutperceived
        function VibrationsnotvisiblebutperceivedValueChanged(app, event)
            value = app.Vibrationsnotvisiblebutperceived.Value;

        end

        % Value changed function: Vibrationsvisiblelowerarm
        function VibrationsvisiblelowerarmValueChanged(app, event)
            value = app.Vibrationsvisiblelowerarm.Value;

        end

        % Button pushed function: SelectForcesFiletxtButton
        function SelectForcesFiletxtButtonPushed(app, event)

            [file,path] = uigetfile('*.txt','Select the .txt file');
            app.SelectedFileForces.Value = file;
            filename = file;

            data = fileread(filename);
            data = strrep(data,',','.');
            fid = fopen(filename,'w');
            fwrite(fid,data,'char');
            fclose(fid);

            opt = {'CollectOutput',true};
            hdr = {};
            out = {};
            [fid,msg] = fopen(filename,'rt');
            assert(fid>=3,msg) % ensure the file opened correctly.
            fgetl(fid); % read and ignore the very first line.
            while ~feof(fid)
                hdr{end+1} = fgetl(fid);
                out(end+1) = textscan(fid,'%f%f',opt{:});
            end
            fclose(fid);

            A0 = out{1,3}

            t1 = A0(:,1)

            A = @(t1) interp1(A0(:,1), A0(:,2), t1, 'linear');

            t = 0:0.01:(size(A0,1)-1)*0.01;


            plot(t,A(t))

            xlabel('Time (s)');

            ylabel('Force (N)');

            hold on

            % F_10 = 10*ones(size(t))
            F_10 = @(t1) 10*ones(size(t1))
            F_1 = 1*ones(size(t));
            plot(t, F_10(t));
            plot(t, F_1);
            legend('Force (Sensor)', '10 N', '1 N')


            hold off


        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            %THIS BUTTON ALLOWS TO EXECUTE INTERNAL FUNCTION "WIRTE EXCEL"
            func12(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.HomeTab = uitab(app.TabGroup);
            app.HomeTab.Title = 'Home';
            app.HARMEvaluationMethodLabel = uilabel(app.HomeTab);
            app.HARMEvaluationMethodLabel.Text = 'HARM Evaluation Method';
            app.DavidRubioEspinosadelosMonterosAliagaLabel = uilabel(app.HomeTab);
            app.DavidRubioEspinosadelosMonterosAliagaLabel.Text = 'David Rubiño Espinosa de los Monteros - Aliaga';
            app.CristinaRodrguezArriazaLabel = uilabel(app.HomeTab);
            app.CristinaRodrguezArriazaLabel.Text = 'Cristina Rodríguez Arriaza';
            app.Image = uiimage(app.HomeTab);
            app.Image.ImageSource = 'Hombros levantados.png';
            app.Image2 = uiimage(app.HomeTab);
            app.Image2.ImageSource = 'cabeza ladeada.png';
            app.ProjectDataTab = uitab(app.TabGroup);
            app.ProjectDataTab.Title = 'Project Data';
            app.DepartmentEditFieldLabel = uilabel(app.ProjectDataTab);
            app.DepartmentEditFieldLabel.Text = 'Department';
            app.Department = uieditfield(app.ProjectDataTab, 'text');
            app.CompletedbyEditFieldLabel = uilabel(app.ProjectDataTab);
            app.CompletedbyEditFieldLabel.Text = 'Completed by';
            app.Completedby = uieditfield(app.ProjectDataTab, 'text');
            app.DateDatePickerLabel = uilabel(app.ProjectDataTab);
            app.DateDatePickerLabel.Text = 'Date';
            app.Date = uidatepicker(app.ProjectDataTab);
            app.SelectFilemvnxButton = uibutton(app.ProjectDataTab, 'push');
            app.SelectFilemvnxButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFilemvnxButtonPushed, true);
            app.SelectFilemvnxButton.Text = 'Select File (.mvnx)';
            app.Label = uilabel(app.ProjectDataTab);
            app.Label.Text = {'File'; ''};
            app.SelectedFile = uieditfield(app.ProjectDataTab, 'text');
            app.SelectedFile.Editable = 'off';
            app.TaskDescriptionEditFieldLabel = uilabel(app.ProjectDataTab);
            app.TaskDescriptionEditFieldLabel.Text = 'Task Description';
            app.TaskDescription = uieditfield(app.ProjectDataTab, 'text');
            app.TaskDescription.ValueChangedFcn = createCallbackFcn(app, @TaskDescriptionValueChanged, true);
            app.TaskDurationTab = uitab(app.TabGroup);
            app.TaskDurationTab.Title = 'Task Duration';
            app.Isabreakofatleast75minutestakenevery15hoursDropDownLabel = uilabel(app.TaskDurationTab);
            app.Isabreakofatleast75minutestakenevery15hoursDropDownLabel.Text = 'Is a break of at least 7.5 minutes taken every 1.5 hours?';
            app.Isabreakofatleast75minutestakenevery15hoursDropDown = uidropdown(app.TaskDurationTab);
            app.Isabreakofatleast75minutestakenevery15hoursDropDown.Items = {'Yes', 'No', 'Select'};
            app.Isabreakofatleast75minutestakenevery15hoursDropDown.ValueChangedFcn = createCallbackFcn(app, @Isabreakofatleast75minutestakenevery15hoursDropDownValueChanged, true);
            app.Isabreakofatleast75minutestakenevery15hoursDropDown.Value = 'Select';
            app.daysLabel = uilabel(app.TaskDurationTab);
            app.daysLabel.Text = 'days';
            app.hoursdayLabel = uilabel(app.TaskDurationTab);
            app.hoursdayLabel.Text = 'hours/day';
            app.DropDown_4Label = uilabel(app.TaskDurationTab);
            app.DropDown_4Label.Text = 'Total time duration of the task over the course of an "average working day"';
            app.taskdurationdropdown = uidropdown(app.TaskDurationTab);
            app.taskdurationdropdown.Items = {'1', '1.5', '2', '2.5', '3', '3.5', '4', '4.5', '5', '5.5', '6', '6.5', '7', '7.5', '8', '8.5', '9', '9.5', '10'};
            app.taskdurationdropdown.ValueChangedFcn = createCallbackFcn(app, @taskdurationdropdownValueChanged, true);
            app.taskdurationdropdown.Value = '5';
            app.HowmanydaysperweekdoesthetaskoccurDropDownLabel = uilabel(app.TaskDurationTab);
            app.HowmanydaysperweekdoesthetaskoccurDropDownLabel.Text = 'How many days per week does the task occur?';
            app.HowmanydaysperweekdoesthetaskoccurDropDown = uidropdown(app.TaskDurationTab);
            app.HowmanydaysperweekdoesthetaskoccurDropDown.Items = {'1', '2', '3', '4', '5', '6'};
            app.HowmanydaysperweekdoesthetaskoccurDropDown.ValueChangedFcn = createCallbackFcn(app, @HowmanydaysperweekdoesthetaskoccurDropDownValueChanged, true);
            app.HowmanydaysperweekdoesthetaskoccurDropDown.Value = '4';
            app.OtherFactorsTab = uitab(app.TabGroup);
            app.OtherFactorsTab.Title = 'Other Factors';
            app.BreakscanonlybetakenatsetbreaktimesDropDownLabel = uilabel(app.OtherFactorsTab);
            app.BreakscanonlybetakenatsetbreaktimesDropDownLabel.Text = {'Breaks can only be taken at set break times'; ''};
            app.Breakscanonlybetakenatsetbreaktimes = uidropdown(app.OtherFactorsTab);
            app.Breakscanonlybetakenatsetbreaktimes.Items = {'Yes', 'No', 'Select'};
            app.Breakscanonlybetakenatsetbreaktimes.ValueChangedFcn = createCallbackFcn(app, @BreakscanonlybetakenatsetbreaktimesValueChanged, true);
            app.Breakscanonlybetakenatsetbreaktimes.Value = 'Select';
            app.WorkwithcoldorwetmaterialsisperformedwithoutglovesDropDownLabel = uilabel(app.OtherFactorsTab);
            app.WorkwithcoldorwetmaterialsisperformedwithoutglovesDropDownLabel.Text = 'Work with cold or wet materials is performed without gloves';
            app.Workwithcoldorwetmaterialsisperformedwithoutgloves = uidropdown(app.OtherFactorsTab);
            app.Workwithcoldorwetmaterialsisperformedwithoutgloves.Items = {'Yes', 'No', 'Select'};
            app.Workwithcoldorwetmaterialsisperformedwithoutgloves.ValueChangedFcn = createCallbackFcn(app, @WorkwithcoldorwetmaterialsisperformedwithoutglovesValueChanged, true);
            app.Workwithcoldorwetmaterialsisperformedwithoutgloves.Value = 'Select';
            app.DropDownLabel = uilabel(app.OtherFactorsTab);
            app.DropDownLabel.Text = 'Disruption to concentration occurs regularly (only if work requires concentration)';
            app.Disruptiontoconcentration = uidropdown(app.OtherFactorsTab);
            app.Disruptiontoconcentration.Items = {'Yes', 'No', 'Select'};
            app.Disruptiontoconcentration.ValueChangedFcn = createCallbackFcn(app, @DisruptiontoconcentrationValueChanged, true);
            app.Disruptiontoconcentration.Value = 'Select';
            app.HandgripsarenotshapedorareslipperyorwetLabel = uilabel(app.OtherFactorsTab);
            app.HandgripsarenotshapedorareslipperyorwetLabel.Text = 'Hand grips are not shaped or are slippery or wet.';
            app.Stretchedfingersora2or3fingerpinchLabel = uilabel(app.OtherFactorsTab);
            app.Stretchedfingersora2or3fingerpinchLabel.Text = ' Stretched fingers or a 2- or 3-finger pinch grip often occur because large or small materials are gripped or held.';
            app.Handsgrip = uidropdown(app.OtherFactorsTab);
            app.Handsgrip.Items = {'Yes', 'No', 'Select'};
            app.Handsgrip.ValueChangedFcn = createCallbackFcn(app, @HandsgripValueChanged, true);
            app.Handsgrip.Value = 'Select';
            app.Label_2 = uilabel(app.OtherFactorsTab);
            app.Label_2.Text = 'The work performed is a precision task. It requires precise positioning or moving of fingers or hands,';
            app.suchasassemblyofverysmallpiecesorsurgicalactionsLabel = uilabel(app.OtherFactorsTab);
            app.suchasassemblyofverysmallpiecesorsurgicalactionsLabel.Text = 'such as assembly of very small pieces or surgical actions';
            app.Precisiontask = uidropdown(app.OtherFactorsTab);
            app.Precisiontask.Items = {'Yes', 'No', 'Select'};
            app.Precisiontask.ValueChangedFcn = createCallbackFcn(app, @PrecisiontaskValueChanged, true);
            app.Precisiontask.Value = 'Select';
            app.ForcesTab = uitab(app.TabGroup);
            app.ForcesTab.Title = 'Forces';
            app.SelectForcesFiletxtButton = uibutton(app.ForcesTab, 'push');
            app.SelectForcesFiletxtButton.ButtonPushedFcn = createCallbackFcn(app, @SelectForcesFiletxtButtonPushed, true);
            app.SelectForcesFiletxtButton.Text = 'Select Forces File (.txt)';
            app.SelectedFileEditFieldLabel = uilabel(app.ForcesTab);
            app.SelectedFileEditFieldLabel.Text = 'Selected File';
            app.SelectedFileForces = uieditfield(app.ForcesTab, 'text');
            app.FORCE1Label = uilabel(app.ForcesTab);
            app.FORCE1Label.Text = 'FORCE 1';
            app.AmountofforceDropDownLabel = uilabel(app.ForcesTab);
            app.AmountofforceDropDownLabel.Text = 'Amount of force';
            app.Amountofforce1 = uidropdown(app.ForcesTab);
            app.Amountofforce1.Items = {'Select', '1 - 10', '10 - 60', 'Peak Force'};
            app.Amountofforce1.Value = 'Select';
            app.DurationoftheforceexertionEditFieldLabel = uilabel(app.ForcesTab);
            app.DurationoftheforceexertionEditFieldLabel.Text = 'Duration of the force exertion';
            app.Durationoftheforceexertion1 = uieditfield(app.ForcesTab, 'numeric');
            app.FrequencyofforceexertionEditFieldLabel = uilabel(app.ForcesTab);
            app.FrequencyofforceexertionEditFieldLabel.Text = 'Frequency of force exertion';
            app.Frequencyofforceexertion1 = uieditfield(app.ForcesTab, 'numeric');
            app.FORCE2Label = uilabel(app.ForcesTab);
            app.FORCE2Label.Text = 'FORCE 2';
            app.Amountofforce2 = uidropdown(app.ForcesTab);
            app.Amountofforce2.Items = {'Select', '1 - 10', '10 - 60', 'Peak Force'};
            app.Amountofforce2.Value = 'Select';
            app.AmountofforceDropDownLabel_2 = uilabel(app.ForcesTab);
            app.AmountofforceDropDownLabel_2.Text = 'Amount of force';
            app.FrequencyofforceexertionEditField_2Label = uilabel(app.ForcesTab);
            app.FrequencyofforceexertionEditField_2Label.Text = 'Frequency of force exertion';
            app.Frequencyofforceexertion2 = uieditfield(app.ForcesTab, 'numeric');
            app.Durationoftheforceexertion2 = uieditfield(app.ForcesTab, 'numeric');
            app.DurationoftheforceexertionEditField_2Label = uilabel(app.ForcesTab);
            app.DurationoftheforceexertionEditField_2Label.Text = 'Duration of the force exertion';
            app.FORCE3Label = uilabel(app.ForcesTab);
            app.FORCE3Label.Text = 'FORCE 3';
            app.Amountofforce3 = uidropdown(app.ForcesTab);
            app.Amountofforce3.Items = {'Select', '1 - 10', '10 - 60', 'Peak Force'};
            app.Amountofforce3.Value = 'Select';
            app.AmountofforceDropDownLabel_3 = uilabel(app.ForcesTab);
            app.AmountofforceDropDownLabel_3.Text = 'Amount of force';
            app.FrequencyofforceexertionEditField_3Label = uilabel(app.ForcesTab);
            app.FrequencyofforceexertionEditField_3Label.Text = 'Frequency of force exertion';
            app.Frequencyofforceexertion3 = uieditfield(app.ForcesTab, 'numeric');
            app.Durationoftheforceexertion3 = uieditfield(app.ForcesTab, 'numeric');
            app.DurationoftheforceexertionEditField_3Label = uilabel(app.ForcesTab);
            app.DurationoftheforceexertionEditField_3Label.Text = 'Duration of the force exertion';
            app.FORCE4Label = uilabel(app.ForcesTab);
            app.FORCE4Label.Text = 'FORCE 4';
            app.Amountofforce4 = uidropdown(app.ForcesTab);
            app.Amountofforce4.Items = {'Select', '1 - 10', '10 - 60', 'Peak Force'};
            app.Amountofforce4.Value = 'Select';
            app.AmountofforceDropDownLabel_4 = uilabel(app.ForcesTab);
            app.AmountofforceDropDownLabel_4.Text = 'Amount of force';
            app.FrequencyofforceexertionEditField_4Label = uilabel(app.ForcesTab);
            app.FrequencyofforceexertionEditField_4Label.Text = 'Frequency of force exertion';
            app.Frequencyofforceexertion4 = uieditfield(app.ForcesTab, 'numeric');
            app.Durationoftheforceexertion4 = uieditfield(app.ForcesTab, 'numeric');
            app.DurationoftheforceexertionEditField_4Label = uilabel(app.ForcesTab);
            app.DurationoftheforceexertionEditField_4Label.Text = 'Duration of the force exertion';
            app.sminLabel = uilabel(app.ForcesTab);
            app.sminLabel.Text = 's/min';
            app.NLabel = uilabel(app.ForcesTab);
            app.NLabel.Text = 'N';
            app.numminLabel = uilabel(app.ForcesTab);
            app.numminLabel.Text = 'num/min';
            app.sminLabel_2 = uilabel(app.ForcesTab);
            app.sminLabel_2.Text = 's/min';
            app.NLabel_2 = uilabel(app.ForcesTab);
            app.NLabel_2.Text = 'N';
            app.numminLabel_2 = uilabel(app.ForcesTab);
            app.numminLabel_2.Text = 'num/min';
            app.sminLabel_3 = uilabel(app.ForcesTab);
            app.sminLabel_3.Text = 's/min';
            app.NLabel_3 = uilabel(app.ForcesTab);
            app.NLabel_3.Text = 'N';
            app.numminLabel_3 = uilabel(app.ForcesTab);
            app.numminLabel_3.Text = 'num/min';
            app.sminLabel_4 = uilabel(app.ForcesTab);
            app.sminLabel_4.Text = 's/min';
            app.NLabel_4 = uilabel(app.ForcesTab);
            app.NLabel_4.Text = 'N';
            app.numminLabel_4 = uilabel(app.ForcesTab);
            app.numminLabel_4.Text = 'num/min';
            app.VibrationsTab = uitab(app.TabGroup);
            app.VibrationsTab.Title = 'Vibrations';
            app.Hardlyanyvibration25msDropDownLabel = uilabel(app.VibrationsTab);
            app.Hardlyanyvibration25msDropDownLabel.Text = 'Hardly any vibration [< 2,5 m/s²]';
            app.Hardlyanyvibration25ms = uidropdown(app.VibrationsTab);
            app.Hardlyanyvibration25ms.Items = {'No vibrating tool used', '0 - 4', '4 - 8', 'Select'};
            app.Hardlyanyvibration25ms.ValueChangedFcn = createCallbackFcn(app, @Hardlyanyvibration25msValueChanged, true);
            app.Hardlyanyvibration25ms.Value = 'Select';
            app.DropDownLabel_2 = uilabel(app.VibrationsTab);
            app.DropDownLabel_2.Text = 'Vibrations not visible, but perceived (quivering sensation) [≥ 2,5 - 5 m/s²]';
            app.Vibrationsnotvisiblebutperceived = uidropdown(app.VibrationsTab);
            app.Vibrationsnotvisiblebutperceived.Items = {'No vibrating tool used', '0 - 4', '4 - 8', 'Select'};
            app.Vibrationsnotvisiblebutperceived.ValueChangedFcn = createCallbackFcn(app, @VibrationsnotvisiblebutperceivedValueChanged, true);
            app.Vibrationsnotvisiblebutperceived.Value = 'Select';
            app.DropDownLabel_3 = uilabel(app.VibrationsTab);
            app.DropDownLabel_3.Text = 'Vibrations just visible on the lower arm/hand, clearly perceived [≥ 5 - 10 m/s²]';
            app.Vibrationsvisiblelowerarm = uidropdown(app.VibrationsTab);
            app.Vibrationsvisiblelowerarm.Items = {'No vibrating tool used', '0 - 4', '4 - 8', 'Select'};
            app.Vibrationsvisiblelowerarm.ValueChangedFcn = createCallbackFcn(app, @VibrationsvisiblelowerarmValueChanged, true);
            app.Vibrationsvisiblelowerarm.Value = 'Select';
            app.DropDownLabel_4 = uilabel(app.VibrationsTab);
            app.DropDownLabel_4.Text = ' The hands, arms or shoulders can be clearly seen to vibrate [≥ 10 m/s²]';
            app.Vibrationsclearlyvisible = uidropdown(app.VibrationsTab);
            app.Vibrationsclearlyvisible.Items = {'No vibrating tool used', '0 - 4', '4 - 8', 'Select'};
            app.Vibrationsclearlyvisible.Value = 'Select';
            app.EvaluationsTab = uitab(app.TabGroup);
            app.EvaluationsTab.Title = 'Evaluations';
            app.TabGroup2 = uitabgroup(app.EvaluationsTab);
            app.JointresultsTab = uitab(app.TabGroup2);
            app.JointresultsTab.Title = 'Joint results';
            app.TabGroup3 = uitabgroup(app.JointresultsTab);
            app.HeadNeck4A15Tab = uitab(app.TabGroup3);
            app.HeadNeck4A15Tab.Title = 'Head/Neck (4A 1-5)';
            app.MaxFlexionLabel = uilabel(app.HeadNeck4A15Tab);
            app.MaxFlexionLabel.Text = 'Max Flexion';
            app.MaxFlexion = uieditfield(app.HeadNeck4A15Tab, 'numeric');
            app.MaxFlexion.ValueChangedFcn = createCallbackFcn(app, @MaxFlexionValueChanged, true);
            app.MaxFlexion.Editable = 'off';
            app.MaxExtensionLabel = uilabel(app.HeadNeck4A15Tab);
            app.MaxExtensionLabel.Text = 'Max Extension';
            app.MaxExtension = uieditfield(app.HeadNeck4A15Tab, 'numeric');
            app.MaxExtension.ValueChangedFcn = createCallbackFcn(app, @MaxExtensionValueChanged, true);
            app.MaxExtension.Editable = 'off';
            app.PercentTimethatHeadistiltedsideward20ORturned45EditFieldLabel = uilabel(app.HeadNeck4A15Tab);
            app.PercentTimethatHeadistiltedsideward20ORturned45EditFieldLabel.Text = 'Percent Time that Head is tilted sideward >20° OR turned >45°';
            app.neck20_45 = uieditfield(app.HeadNeck4A15Tab, 'numeric');
            app.neck20_45.ValueChangedFcn = createCallbackFcn(app, @neck20_45ValueChanged, true);
            app.neck20_45.Editable = 'off';
            app.PercentTimethatHeadistiltedForward20ANDturned45EditFieldLabel = uilabel(app.HeadNeck4A15Tab);
            app.PercentTimethatHeadistiltedForward20ANDturned45EditFieldLabel.Text = 'Percent Time that Head is tilted Forward >20° AND turned >45°';
            app.neck20and45 = uieditfield(app.HeadNeck4A15Tab, 'numeric');
            app.neck20and45.ValueChangedFcn = createCallbackFcn(app, @neck20and45ValueChanged, true);
            app.neck20and45.Editable = 'off';
            app.PercentTimethatHeadistiltedbackward10ANDturned45EditFieldLabel = uilabel(app.HeadNeck4A15Tab);
            app.PercentTimethatHeadistiltedbackward10ANDturned45EditFieldLabel.Text = 'Percent Time that Head is tilted backward >10° AND turned >45°';
            app.neck10and45 = uieditfield(app.HeadNeck4A15Tab, 'numeric');
            app.neck10and45.ValueChangedFcn = createCallbackFcn(app, @neck10and45ValueChanged, true);
            app.neck10and45.Editable = 'off';
            app.STEP4A1Label = uilabel(app.HeadNeck4A15Tab);
            app.STEP4A1Label.Text = 'STEP 4A-1';
            app.STEP4A2Label = uilabel(app.HeadNeck4A15Tab);
            app.STEP4A2Label.Text = 'STEP 4A-2';
            app.STEP4A3Label = uilabel(app.HeadNeck4A15Tab);
            app.STEP4A3Label.Text = 'STEP 4A-3';
            app.STEP4A4Label = uilabel(app.HeadNeck4A15Tab);
            app.STEP4A4Label.Text = 'STEP 4A-4';
            app.ADDITIONALDATALabel = uilabel(app.HeadNeck4A15Tab);
            app.ADDITIONALDATALabel.Text = 'ADDITIONAL DATA';
            app.MaxRotationREditFieldLabel = uilabel(app.HeadNeck4A15Tab);
            app.MaxRotationREditFieldLabel.Text = 'Max Rotation (R)';
            app.MaxRotationR = uieditfield(app.HeadNeck4A15Tab, 'numeric');
            app.MaxRotationR.ValueChangedFcn = createCallbackFcn(app, @MaxRotationRValueChanged, true);
            app.MaxRotationR.Editable = 'off';
            app.MaxRotationLEditFieldLabel = uilabel(app.HeadNeck4A15Tab);
            app.MaxRotationLEditFieldLabel.Text = 'Max Rotation (L)';
            app.MaxRotationL = uieditfield(app.HeadNeck4A15Tab, 'numeric');
            app.MaxRotationL.ValueChangedFcn = createCallbackFcn(app, @MaxRotationLValueChanged, true);
            app.MaxRotationL.Editable = 'off';
            app.PercentTimethatHeadistiltedforward20ORtiltedbackward1045Label = uilabel(app.HeadNeck4A15Tab);
            app.PercentTimethatHeadistiltedforward20ORtiltedbackward1045Label.Text = 'Percent Time that Head is tilted forward >20° OR tilted backward >10º';
            app.neck20_10 = uieditfield(app.HeadNeck4A15Tab, 'numeric');
            app.neck20_10.ValueChangedFcn = createCallbackFcn(app, @neck20_10ValueChanged, true);
            app.neck20_10.Editable = 'off';
            app.Label_3 = uilabel(app.HeadNeck4A15Tab);
            app.Label_3.Text = 'º';
            app.Label_4 = uilabel(app.HeadNeck4A15Tab);
            app.Label_4.Text = 'º';
            app.Label_6 = uilabel(app.HeadNeck4A15Tab);
            app.Label_6.Text = 'º';
            app.Label_7 = uilabel(app.HeadNeck4A15Tab);
            app.Label_7.Text = 'º';
            app.Label_8 = uilabel(app.HeadNeck4A15Tab);
            app.Label_8.Text = '%';
            app.Label_10 = uilabel(app.HeadNeck4A15Tab);
            app.Label_10.Text = '%';
            app.Label_11 = uilabel(app.HeadNeck4A15Tab);
            app.Label_11.Text = '%';
            app.Label_12 = uilabel(app.HeadNeck4A15Tab);
            app.Label_12.Text = '%';
            app.STEP4A5Label = uilabel(app.HeadNeck4A15Tab);
            app.STEP4A5Label.Text = 'STEP 4A-5';
            app.HeadchinpushedextendedforwardEditFieldLabel = uilabel(app.HeadNeck4A15Tab);
            app.HeadchinpushedextendedforwardEditFieldLabel.Text = 'Head (chin) pushed (extended) forward';
            app.Headchinpushedextendedforward = uieditfield(app.HeadNeck4A15Tab, 'numeric');
            app.Headchinpushedextendedforward.ValueChangedFcn = createCallbackFcn(app, @HeadchinpushedextendedforwardValueChanged, true);
            app.Headchinpushedextendedforward.Editable = 'off';
            app.Label_23 = uilabel(app.HeadNeck4A15Tab);
            app.Label_23.Text = '%';
            app.MaxHeadExtensionEditFieldLabel = uilabel(app.HeadNeck4A15Tab);
            app.MaxHeadExtensionEditFieldLabel.Text = 'Max Head Extension';
            app.MaxHeadExtensionEditField = uieditfield(app.HeadNeck4A15Tab, 'numeric');
            app.MaxHeadExtensionEditField.ValueChangedFcn = createCallbackFcn(app, @MaxHeadExtensionEditFieldValueChanged, true);
            app.cmLabel = uilabel(app.HeadNeck4A15Tab);
            app.cmLabel.Text = ' cm';
            app.StepScoreLabel = uilabel(app.HeadNeck4A15Tab);
            app.StepScoreLabel.Text = 'Step Score';
            app.score4a1 = uieditfield(app.HeadNeck4A15Tab, 'numeric');
            app.score4a1.Editable = 'off';
            app.score4a2 = uieditfield(app.HeadNeck4A15Tab, 'numeric');
            app.score4a2.Editable = 'off';
            app.score4a3 = uieditfield(app.HeadNeck4A15Tab, 'numeric');
            app.score4a3.Editable = 'off';
            app.score4a4 = uieditfield(app.HeadNeck4A15Tab, 'numeric');
            app.score4a4.Editable = 'off';
            app.score4a5 = uieditfield(app.HeadNeck4A15Tab, 'numeric');
            app.score4a5.Editable = 'off';
            app.Shoulders4A67Tab = uitab(app.TabGroup3);
            app.Shoulders4A67Tab.Title = 'Shoulders (4A 6-7)';
            app.Upperarmforward30ORsideward30ORbackwards30RightEditFieldLabel = uilabel(app.Shoulders4A67Tab);
            app.Upperarmforward30ORsideward30ORbackwards30RightEditFieldLabel.Text = 'Upper arm forward >30° OR sideward >30° OR backwards >30° (Right)';
            app.Upperarmforward30ORsideward30ORbackwards30Right = uieditfield(app.Shoulders4A67Tab, 'numeric');
            app.Upperarmforward30ORsideward30ORbackwards30Right.ValueChangedFcn = createCallbackFcn(app, @Upperarmforward30ORsideward30ORbackwards30RightValueChanged, true);
            app.Upperarmforward30ORsideward30ORbackwards30Right.Editable = 'off';
            app.Upperarmforward30ORsideward30ORbackwards30LeftEditFieldLabel = uilabel(app.Shoulders4A67Tab);
            app.Upperarmforward30ORsideward30ORbackwards30LeftEditFieldLabel.Text = 'Upper arm forward >30° OR sideward >30° OR backwards >30° (Left)';
            app.Upperarmforward30ORsideward30ORbackwards30Left = uieditfield(app.Shoulders4A67Tab, 'numeric');
            app.Upperarmforward30ORsideward30ORbackwards30Left.ValueChangedFcn = createCallbackFcn(app, @Upperarmforward30ORsideward30ORbackwards30LeftValueChanged, true);
            app.Upperarmforward30ORsideward30ORbackwards30Left.Editable = 'off';
            app.STEP4A6Label = uilabel(app.Shoulders4A67Tab);
            app.STEP4A6Label.Text = 'STEP 4A-6';
            app.STEP4A7Label = uilabel(app.Shoulders4A67Tab);
            app.STEP4A7Label.Text = 'STEP 4A-7';
            app.Shouldersraisedhigh3cmEditFieldLabel = uilabel(app.Shoulders4A67Tab);
            app.Shouldersraisedhigh3cmEditFieldLabel.Text = 'Shoulders raised (high) > 3 cm';
            app.Shouldersraisedhigh3cm = uieditfield(app.Shoulders4A67Tab, 'numeric');
            app.Shouldersraisedhigh3cm.ValueChangedFcn = createCallbackFcn(app, @Shouldersraisedhigh3cmValueChanged, true);
            app.Shouldersraisedhigh3cm.Editable = 'off';
            app.Label_20 = uilabel(app.Shoulders4A67Tab);
            app.Label_20.Text = '%';
            app.Label_21 = uilabel(app.Shoulders4A67Tab);
            app.Label_21.Text = '%';
            app.Label_22 = uilabel(app.Shoulders4A67Tab);
            app.Label_22.Text = '%';
            app.StepScoreLabel_2 = uilabel(app.Shoulders4A67Tab);
            app.StepScoreLabel_2.Text = 'Step Score';
            app.score4a6R = uieditfield(app.Shoulders4A67Tab, 'numeric');
            app.score4a6R.Editable = 'off';
            app.score4a6L = uieditfield(app.Shoulders4A67Tab, 'numeric');
            app.score4a6L.Editable = 'off';
            app.score4a7 = uieditfield(app.Shoulders4A67Tab, 'numeric');
            app.score4a7.Editable = 'off';
            app.ADDITIONALDATALabel_4 = uilabel(app.Shoulders4A67Tab);
            app.ADDITIONALDATALabel_4.Text = 'ADDITIONAL DATA';
            app.Label_47 = uilabel(app.Shoulders4A67Tab);
            app.Label_47.Text = 'º';
            app.Label_48 = uilabel(app.Shoulders4A67Tab);
            app.Label_48.Text = 'º';
            app.MaxFlexionLabel_2 = uilabel(app.Shoulders4A67Tab);
            app.MaxFlexionLabel_2.Text = 'Max shoulder forward (Left)';
            app.shoulderforwardsleft = uieditfield(app.Shoulders4A67Tab, 'numeric');
            app.shoulderforwardsleft.Editable = 'off';
            app.MaxExtensionLabel_2 = uilabel(app.Shoulders4A67Tab);
            app.MaxExtensionLabel_2.Text = 'Max shoulder backwards (Left)';
            app.shoulderbackwardsleft = uieditfield(app.Shoulders4A67Tab, 'numeric');
            app.shoulderbackwardsleft.Editable = 'off';
            app.Label_51 = uilabel(app.Shoulders4A67Tab);
            app.Label_51.Text = 'º';
            app.Label_52 = uilabel(app.Shoulders4A67Tab);
            app.Label_52.Text = 'º';
            app.MaxFlexionLabel_4 = uilabel(app.Shoulders4A67Tab);
            app.MaxFlexionLabel_4.Text = 'Max shoulder sidewards to right (Left)';
            app.shouldersidewardsrightleft = uieditfield(app.Shoulders4A67Tab, 'numeric');
            app.shouldersidewardsrightleft.Editable = 'off';
            app.MaxExtensionLabel_4 = uilabel(app.Shoulders4A67Tab);
            app.MaxExtensionLabel_4.Text = 'Max shoulder sidewards to left (Left)';
            app.shouldersidewardsleftleft = uieditfield(app.Shoulders4A67Tab, 'numeric');
            app.shouldersidewardsleftleft.Editable = 'off';
            app.Label_53 = uilabel(app.Shoulders4A67Tab);
            app.Label_53.Text = 'º';
            app.Label_54 = uilabel(app.Shoulders4A67Tab);
            app.Label_54.Text = 'º';
            app.Label_55 = uilabel(app.Shoulders4A67Tab);
            app.Label_55.Text = 'º';
            app.Label_56 = uilabel(app.Shoulders4A67Tab);
            app.Label_56.Text = 'º';
            app.MaxFlexionLabel_5 = uilabel(app.Shoulders4A67Tab);
            app.MaxFlexionLabel_5.Text = 'Max shoulder forward (Right)';
            app.shoulderforwardright = uieditfield(app.Shoulders4A67Tab, 'numeric');
            app.shoulderforwardright.Editable = 'off';
            app.MaxExtensionLabel_5 = uilabel(app.Shoulders4A67Tab);
            app.MaxExtensionLabel_5.Text = 'Max shoulder backwards (Right)';
            app.shoulderbackwardsright = uieditfield(app.Shoulders4A67Tab, 'numeric');
            app.shoulderbackwardsright.Editable = 'off';
            app.MaxFlexionLabel_6 = uilabel(app.Shoulders4A67Tab);
            app.MaxFlexionLabel_6.Text = 'Max shoulder sidewards to right (Right)';
            app.shouldersidewardsrightright = uieditfield(app.Shoulders4A67Tab, 'numeric');
            app.shouldersidewardsrightright.Editable = 'off';
            app.MaxExtensionLabel_6 = uilabel(app.Shoulders4A67Tab);
            app.MaxExtensionLabel_6.Text = 'Max shoulder sidewards to left (Right)';
            app.shouldersidewardsleftright = uieditfield(app.Shoulders4A67Tab, 'numeric');
            app.shouldersidewardsleftright.Editable = 'off';
            app.Elbows4B12Tab = uitab(app.TabGroup3);
            app.Elbows4B12Tab.Title = 'Elbows (4B 1-2)';
            app.STEP4B1Label = uilabel(app.Elbows4B12Tab);
            app.STEP4B1Label.Text = 'STEP 4B-1';
            app.ElbowbentTORextendedURightEditFieldLabel = uilabel(app.Elbows4B12Tab);
            app.ElbowbentTORextendedURightEditFieldLabel.Text = 'Elbow bent >T° OR extended >U° (Right)';
            app.ElbowbentTORextendedURight = uieditfield(app.Elbows4B12Tab, 'numeric');
            app.ElbowbentTORextendedURight.ValueChangedFcn = createCallbackFcn(app, @ElbowbentTORextendedURightValueChanged, true);
            app.ElbowbentTORextendedURight.Editable = 'off';
            app.STEP4B2Label = uilabel(app.Elbows4B12Tab);
            app.STEP4B2Label.Text = 'STEP 4B-2';
            app.Lowerarmrotated40fromneutralRightEditFieldLabel = uilabel(app.Elbows4B12Tab);
            app.Lowerarmrotated40fromneutralRightEditFieldLabel.Text = 'Lower arm rotated >40° from neutral (Right)';
            app.Lowerarmrotated40fromneutralRight = uieditfield(app.Elbows4B12Tab, 'numeric');
            app.Lowerarmrotated40fromneutralRight.ValueChangedFcn = createCallbackFcn(app, @Lowerarmrotated40fromneutralRightValueChanged, true);
            app.Lowerarmrotated40fromneutralRight.Editable = 'off';
            app.ElbowbentTORextendedULeftEditFieldLabel = uilabel(app.Elbows4B12Tab);
            app.ElbowbentTORextendedULeftEditFieldLabel.Text = 'Elbow bent >T° OR extended >U° (Left)';
            app.ElbowbentTORextendedULeft = uieditfield(app.Elbows4B12Tab, 'numeric');
            app.ElbowbentTORextendedULeft.ValueChangedFcn = createCallbackFcn(app, @ElbowbentTORextendedULeftValueChanged, true);
            app.ElbowbentTORextendedULeft.Editable = 'off';
            app.Lowerarmrotated40fromneutralLeftEditFieldLabel = uilabel(app.Elbows4B12Tab);
            app.Lowerarmrotated40fromneutralLeftEditFieldLabel.Text = 'Lower arm rotated >40° from neutral (Left)';
            app.Lowerarmrotated40fromneutralLeft = uieditfield(app.Elbows4B12Tab, 'numeric');
            app.Lowerarmrotated40fromneutralLeft.ValueChangedFcn = createCallbackFcn(app, @Lowerarmrotated40fromneutralLeftValueChanged, true);
            app.Lowerarmrotated40fromneutralLeft.Editable = 'off';
            app.ADDITIONALDATALabel_2 = uilabel(app.Elbows4B12Tab);
            app.ADDITIONALDATALabel_2.Text = 'ADDITIONAL DATA';
            app.MaxlowerarmflexionLeftEditFieldLabel = uilabel(app.Elbows4B12Tab);
            app.MaxlowerarmflexionLeftEditFieldLabel.Text = 'Max lower arm flexion (Left)';
            app.MaxlowerarmflexionLeftEditField = uieditfield(app.Elbows4B12Tab, 'numeric');
            app.MaxlowerarmflexionLeftEditField.ValueChangedFcn = createCallbackFcn(app, @MaxlowerarmflexionLeftEditFieldValueChanged, true);
            app.MaxlowerarmflexionLeftEditField.Editable = 'off';
            app.MaxlowerarmpronationLeftEditFieldLabel = uilabel(app.Elbows4B12Tab);
            app.MaxlowerarmpronationLeftEditFieldLabel.Text = 'Max lower arm pronation (Left)';
            app.MaxlowerarmpronationLeftEditField = uieditfield(app.Elbows4B12Tab, 'numeric');
            app.MaxlowerarmpronationLeftEditField.ValueChangedFcn = createCallbackFcn(app, @MaxlowerarmpronationLeftEditFieldValueChanged, true);
            app.MaxlowerarmpronationLeftEditField.Editable = 'off';
            app.MaxlowerarmsupinationLeftEditFieldLabel = uilabel(app.Elbows4B12Tab);
            app.MaxlowerarmsupinationLeftEditFieldLabel.Text = 'Max lower arm supination (Left)';
            app.MaxlowerarmsupinationLeftEditField = uieditfield(app.Elbows4B12Tab, 'numeric');
            app.MaxlowerarmsupinationLeftEditField.ValueChangedFcn = createCallbackFcn(app, @MaxlowerarmsupinationLeftEditFieldValueChanged, true);
            app.MaxlowerarmsupinationLeftEditField.Editable = 'off';
            app.MaxlowerarmflexionRightEditFieldLabel = uilabel(app.Elbows4B12Tab);
            app.MaxlowerarmflexionRightEditFieldLabel.Text = 'Max lower arm flexion (Right)';
            app.MaxlowerarmflexionRightEditField = uieditfield(app.Elbows4B12Tab, 'numeric');
            app.MaxlowerarmflexionRightEditField.ValueChangedFcn = createCallbackFcn(app, @MaxlowerarmflexionRightEditFieldValueChanged, true);
            app.MaxlowerarmflexionRightEditField.Editable = 'off';
            app.MaxlowerarmpronationRightEditField_2Label = uilabel(app.Elbows4B12Tab);
            app.MaxlowerarmpronationRightEditField_2Label.Text = 'Max lower arm pronation (Right)';
            app.MaxlowerarmpronationRightEditField_2 = uieditfield(app.Elbows4B12Tab, 'numeric');
            app.MaxlowerarmpronationRightEditField_2.ValueChangedFcn = createCallbackFcn(app, @MaxlowerarmpronationRightEditField_2ValueChanged, true);
            app.MaxlowerarmpronationRightEditField_2.Editable = 'off';
            app.MaxlowerarmsupinationRightEditField_2Label = uilabel(app.Elbows4B12Tab);
            app.MaxlowerarmsupinationRightEditField_2Label.Text = 'Max lower arm supination (Right)';
            app.MaxlowerarmsupinationRightEditField_2 = uieditfield(app.Elbows4B12Tab, 'numeric');
            app.MaxlowerarmsupinationRightEditField_2.ValueChangedFcn = createCallbackFcn(app, @MaxlowerarmsupinationRightEditField_2ValueChanged, true);
            app.MaxlowerarmsupinationRightEditField_2.Editable = 'off';
            app.Label_24 = uilabel(app.Elbows4B12Tab);
            app.Label_24.Text = '%';
            app.Label_25 = uilabel(app.Elbows4B12Tab);
            app.Label_25.Text = '%';
            app.Label_26 = uilabel(app.Elbows4B12Tab);
            app.Label_26.Text = '%';
            app.Label_27 = uilabel(app.Elbows4B12Tab);
            app.Label_27.Text = '%';
            app.Label_29 = uilabel(app.Elbows4B12Tab);
            app.Label_29.Text = 'º';
            app.Label_30 = uilabel(app.Elbows4B12Tab);
            app.Label_30.Text = 'º';
            app.Label_31 = uilabel(app.Elbows4B12Tab);
            app.Label_31.Text = 'º';
            app.Label_33 = uilabel(app.Elbows4B12Tab);
            app.Label_33.Text = 'º';
            app.Label_34 = uilabel(app.Elbows4B12Tab);
            app.Label_34.Text = 'º';
            app.Label_35 = uilabel(app.Elbows4B12Tab);
            app.Label_35.Text = 'º';
            app.StepScoreLabel_3 = uilabel(app.Elbows4B12Tab);
            app.StepScoreLabel_3.Text = 'Step Score';
            app.score4b1R = uieditfield(app.Elbows4B12Tab, 'numeric');
            app.score4b1R.Editable = 'off';
            app.score4b1L = uieditfield(app.Elbows4B12Tab, 'numeric');
            app.score4b1L.Editable = 'off';
            app.score4b2R = uieditfield(app.Elbows4B12Tab, 'numeric');
            app.score4b2R.Editable = 'off';
            app.score4b2L = uieditfield(app.Elbows4B12Tab, 'numeric');
            app.score4b2L.Editable = 'off';
            app.Wrists4B34Tab = uitab(app.TabGroup3);
            app.Wrists4B34Tab.Title = 'Wrists (4B 3-4)';
            app.STEP4B3Label = uilabel(app.Wrists4B34Tab);
            app.STEP4B3Label.Text = 'STEP 4B-3';
            app.STEP4B4Label = uilabel(app.Wrists4B34Tab);
            app.STEP4B4Label.Text = 'STEP 4B-4';
            app.ADDITIONALDATALabel_3 = uilabel(app.Wrists4B34Tab);
            app.ADDITIONALDATALabel_3.Text = 'ADDITIONAL DATA';
            app.Label_36 = uilabel(app.Wrists4B34Tab);
            app.Label_36.Text = '%';
            app.Label_37 = uilabel(app.Wrists4B34Tab);
            app.Label_37.Text = '%';
            app.Label_38 = uilabel(app.Wrists4B34Tab);
            app.Label_38.Text = '%';
            app.Label_39 = uilabel(app.Wrists4B34Tab);
            app.Label_39.Text = '%';
            app.Label_40 = uilabel(app.Wrists4B34Tab);
            app.Label_40.Text = 'º';
            app.Label_41 = uilabel(app.Wrists4B34Tab);
            app.Label_41.Text = 'º';
            app.Label_42 = uilabel(app.Wrists4B34Tab);
            app.Label_42.Text = 'º';
            app.Label_43 = uilabel(app.Wrists4B34Tab);
            app.Label_43.Text = 'º';
            app.Label_44 = uilabel(app.Wrists4B34Tab);
            app.Label_44.Text = 'º';
            app.Label_45 = uilabel(app.Wrists4B34Tab);
            app.Label_45.Text = 'º';
            app.StepScoreLabel_4 = uilabel(app.Wrists4B34Tab);
            app.StepScoreLabel_4.Text = 'Step Score';
            app.score4b3R = uieditfield(app.Wrists4B34Tab, 'numeric');
            app.score4b3R.Editable = 'off';
            app.score4b3L = uieditfield(app.Wrists4B34Tab, 'numeric');
            app.score4b3L.Editable = 'off';
            app.score4b4R = uieditfield(app.Wrists4B34Tab, 'numeric');
            app.score4b4R.Editable = 'off';
            app.score4b4L = uieditfield(app.Wrists4B34Tab, 'numeric');
            app.score4b4L.Editable = 'off';
            app.Handbentsidewaysatthewrist10RightEditFieldLabel = uilabel(app.Wrists4B34Tab);
            app.Handbentsidewaysatthewrist10RightEditFieldLabel.Text = 'Hand bent sideways at the wrist >10° (Right)';
            app.Handbentsidewaysatthewrist10Right = uieditfield(app.Wrists4B34Tab, 'numeric');
            app.Handbentsidewaysatthewrist10Right.Editable = 'off';
            app.Thehandisbentatthewrist15RightEditFieldLabel = uilabel(app.Wrists4B34Tab);
            app.Thehandisbentatthewrist15RightEditFieldLabel.Text = 'The hand is bent at the wrist >15° (Right)';
            app.Thehandisbentatthewrist15Right = uieditfield(app.Wrists4B34Tab, 'numeric');
            app.Thehandisbentatthewrist15Right.Editable = 'off';
            app.Handbentsidewaysatthewrist10LeftEditFieldLabel = uilabel(app.Wrists4B34Tab);
            app.Handbentsidewaysatthewrist10LeftEditFieldLabel.Text = 'Hand bent sideways at the wrist >10° (Left)';
            app.Handbentsidewaysatthewrist10Left = uieditfield(app.Wrists4B34Tab, 'numeric');
            app.Handbentsidewaysatthewrist10Left.Editable = 'off';
            app.Thehandisbentatthewrist15LeftEditFieldLabel = uilabel(app.Wrists4B34Tab);
            app.Thehandisbentatthewrist15LeftEditFieldLabel.Text = 'The hand is bent at the wrist >15° (Left)';
            app.Thehandisbentatthewrist15Left = uieditfield(app.Wrists4B34Tab, 'numeric');
            app.Thehandisbentatthewrist15Left.Editable = 'off';
            app.MaxwristflexionLeftEditFieldLabel = uilabel(app.Wrists4B34Tab);
            app.MaxwristflexionLeftEditFieldLabel.Text = 'Max wrist flexion (Left)';
            app.MaxwristflexionLeft = uieditfield(app.Wrists4B34Tab, 'numeric');
            app.MaxwristflexionLeft.Editable = 'off';
            app.MaxwristbentsidewaystoleftLeftEditFieldLabel = uilabel(app.Wrists4B34Tab);
            app.MaxwristbentsidewaystoleftLeftEditFieldLabel.Text = 'Max wrist bent sideways to left (Left)';
            app.MaxwristbentsidewaystoleftLeft = uieditfield(app.Wrists4B34Tab, 'numeric');
            app.MaxwristbentsidewaystoleftLeft.Editable = 'off';
            app.MaxwristbentsidewaystorightLeftEditFieldLabel = uilabel(app.Wrists4B34Tab);
            app.MaxwristbentsidewaystorightLeftEditFieldLabel.Text = 'Max wrist bent sideways to right (Left)';
            app.MaxwristbentsidewaystorightLeft = uieditfield(app.Wrists4B34Tab, 'numeric');
            app.MaxwristbentsidewaystorightLeft.Editable = 'off';
            app.MaxwristflexionRightEditFieldLabel = uilabel(app.Wrists4B34Tab);
            app.MaxwristflexionRightEditFieldLabel.Text = 'Max wrist flexion (Right)';
            app.MaxwristflexionRight = uieditfield(app.Wrists4B34Tab, 'numeric');
            app.MaxwristflexionRight.Editable = 'off';
            app.MaxwristbentsidewaystoleftRightEditFieldLabel = uilabel(app.Wrists4B34Tab);
            app.MaxwristbentsidewaystoleftRightEditFieldLabel.Text = 'Max wrist bent sideways to left (Right)';
            app.MaxwristbentsidewaystoleftRight = uieditfield(app.Wrists4B34Tab, 'numeric');
            app.MaxwristbentsidewaystoleftRight.Editable = 'off';
            app.MaxwristbentsidewaystorightRightEditFieldLabel = uilabel(app.Wrists4B34Tab);
            app.MaxwristbentsidewaystorightRightEditFieldLabel.Text = 'Max wrist bent sideways to right (Right)';
            app.MaxwristbentsidewaystorightRight = uieditfield(app.Wrists4B34Tab, 'numeric');
            app.MaxwristbentsidewaystorightRight.Editable = 'off';
            app.MaxwristextensionLeftLabel = uilabel(app.Wrists4B34Tab);
            app.MaxwristextensionLeftLabel.Text = 'Max wrist extension (Left)';
            app.MaxwristExtensionLeft = uieditfield(app.Wrists4B34Tab, 'numeric');
            app.MaxwristExtensionLeft.Editable = 'off';
            app.MaxwristextensionLeftLabel_2 = uilabel(app.Wrists4B34Tab);
            app.MaxwristextensionLeftLabel_2.Text = 'Max wrist extension (Right)';
            app.MaxwristextensionR = uieditfield(app.Wrists4B34Tab, 'numeric');
            app.MaxwristextensionR.Editable = 'off';
            app.Label_57 = uilabel(app.Wrists4B34Tab);
            app.Label_57.Text = 'º';
            app.Label_58 = uilabel(app.Wrists4B34Tab);
            app.Label_58.Text = 'º';
            app.HARMEvaluationTab = uitab(app.TabGroup2);
            app.HARMEvaluationTab.Title = 'HARM Evaluation';
            app.HARMEvaluationTab.ButtonDownFcn = createCallbackFcn(app, @HARMEvaluationTabButtonDown, true);
            app.HARMRiskScoreLeftArmLabel = uilabel(app.HARMEvaluationTab);
            app.HARMRiskScoreLeftArmLabel.Text = 'HARM Risk Score (Left Arm)';
            app.HARMRiskScoreL = uieditfield(app.HARMEvaluationTab, 'numeric');
            app.HARMRiskScoreL.ValueChangedFcn = createCallbackFcn(app, @HARMRiskScoreLValueChanged, true);
            app.HARMRiskScoreL.Editable = 'off';
            app.Label_13 = uilabel(app.HARMEvaluationTab);
            app.Label_13.Text = '>50';
            app.Label_14 = uilabel(app.HARMEvaluationTab);
            app.Label_14.Text = 'High risk of arm, neck or shoulder complaints. It is important to take preventative measures immediately.';
            app.Label_15 = uilabel(app.HARMEvaluationTab);
            app.Label_15.Text = '30-50';
            app.Label_16 = uilabel(app.HARMEvaluationTab);
            app.Label_16.Text = 'Increased risk of arm, neck or shoulder complaints for some employees.';
            app.Label_17 = uilabel(app.HARMEvaluationTab);
            app.Label_17.Text = ' In order to protect all employees, it is important to take preventative measures that lower the risk.';
            app.Label_18 = uilabel(app.HARMEvaluationTab);
            app.Label_18.Text = '<30';
            app.Label_19 = uilabel(app.HARMEvaluationTab);
            app.Label_19.Text = 'No risk of arm, neck or shoulder complaints for virtually the entire working population.';
            app.HARMRiskScoreLeftArmLabel_2 = uilabel(app.HARMEvaluationTab);
            app.HARMRiskScoreLeftArmLabel_2.Text = 'HARM Risk Score (Right Arm)';
            app.HARMRiskScoreR = uieditfield(app.HARMEvaluationTab, 'numeric');
            app.HARMRiskScoreR.Editable = 'off';
            app.MostactivearmTab = uitab(app.TabGroup2);
            app.MostactivearmTab.Title = 'Most active arm';
            app.ThemostactivearmistheLabel = uilabel(app.MostactivearmTab);
            app.ThemostactivearmistheLabel.Text = 'The most active arm is the';
            app.mostactivearm = uieditfield(app.MostactivearmTab, 'text');
            app.byEditFieldLabel = uilabel(app.MostactivearmTab);
            app.byEditFieldLabel.Text = 'by';
            app.mostactivearmpercent = uieditfield(app.MostactivearmTab, 'numeric');
            app.Label_46 = uilabel(app.MostactivearmTab);
            app.Label_46.Text = '%';
            app.EvaluateButton = uibutton(app.EvaluationsTab, 'push');
            app.EvaluateButton.ButtonPushedFcn = createCallbackFcn(app, @RunProgramButtonPushed, true);
            app.EvaluateButton.Text = 'Evaluate';
            app.SaveButton = uibutton(app.EvaluationsTab, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Interface_V5_1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end